classdef medianFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MEDIANFILTERLabel  matlab.ui.control.Label
        FilterImageButton  matlab.ui.control.Button
        LoadImageButton    matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick a image to filter');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a, "Parent",app.UIAxes);
        end

        % Button pushed function: FilterImageButton
        function FilterImageButtonPushed(app, event)
            global a;
            j = a;
            j = medfilt3(a);
            imshow(j, "Parent",app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered Image')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.FilterImageButton = uibutton(app.UIFigure, 'push');
            app.FilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterImageButtonPushed, true);
            app.FilterImageButton.Text = 'Filter Image';
            app.MEDIANFILTERLabel = uilabel(app.UIFigure);
            app.MEDIANFILTERLabel.Text = 'MEDIAN FILTER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medianFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end